classdef DialogAppAponeurosis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Label                       matlab.ui.control.Label
        GridLayout3                 matlab.ui.container.GridLayout
        ConfirmButton               matlab.ui.control.Button
        GridLayout2                 matlab.ui.container.GridLayout
        PartsofAponeurosisDropDown  matlab.ui.control.DropDown
        PartsofAponeurosisDropDownLabel  matlab.ui.control.Label
        AponeurosisLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
        SupInfApo
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, SupInfApo)
            
            % Store main app in property for ConfirmFcn to use
            app.CallingApp = mainapp;
            app.SupInfApo = SupInfApo;
            % Update Label with input values
            app.AponeurosisLabel.Text = ['Aponeurosis ',app.SupInfApo];
            
            app.PartsofAponeurosisDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
        end

        % Close request function: UIFigure
        function closeRequestFcn(app, event)
            delete(app)
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtPush(app, event)
            app.UIFigure.Visible = 'off';
            % call main app's Confirm function
            ApoSelectFcn(app.CallingApp,app.PartsofAponeurosisDropDown.Value)
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequestFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AponeurosisLabel = uilabel(app.GridLayout);
            app.AponeurosisLabel.Text = 'Aponeurosis';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PartsofAponeurosisDropDownLabel = uilabel(app.GridLayout2);
            app.PartsofAponeurosisDropDownLabel.Text = 'Parts of Aponeurosis';
            app.PartsofAponeurosisDropDown = uidropdown(app.GridLayout2);
            app.PartsofAponeurosisDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.PartsofAponeurosisDropDown.Value = '1';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ConfirmButton = uibutton(app.GridLayout3, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtPush, true);
            app.ConfirmButton.Text = 'Confirm';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Select the number of parts of the aponeurosis without discontinuities.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppAponeurosis(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end