classdef DialogAppCorrections < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        FasciclesPanel    matlab.ui.container.Panel
        GridLayout5       matlab.ui.container.GridLayout
        GridLayout7       matlab.ui.container.GridLayout
        F5CheckBox        matlab.ui.control.CheckBox
        F4CheckBox        matlab.ui.control.CheckBox
        ImF5              matlab.ui.control.Image
        ImF4              matlab.ui.control.Image
        GridLayout6       matlab.ui.container.GridLayout
        F3CheckBox        matlab.ui.control.CheckBox
        F2CheckBox        matlab.ui.control.CheckBox
        F1CheckBox        matlab.ui.control.CheckBox
        ImF3              matlab.ui.control.Image
        ImF2              matlab.ui.control.Image
        ImF1              matlab.ui.control.Image
        AponeurosisPanel  matlab.ui.container.Panel
        GridLayout4       matlab.ui.container.GridLayout
        ApoInfCheckBox    matlab.ui.control.CheckBox
        ApoSupCheckBox    matlab.ui.control.CheckBox
        Image2            matlab.ui.control.Image
        Image             matlab.ui.control.Image
        GridLayout3       matlab.ui.container.GridLayout
        ConfirmButton     matlab.ui.control.Button
        CorrectionsLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        callingapp % Description
        NbFl
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, NbFl)
            app.callingapp = mainapp;

            app.NbFl = NbFl;

            switch NbFl
                case 1
                    app.ImF1.Enable = 'on';
                    app.F1CheckBox.Enable = 'on';
                case 2
                    app.ImF1.Enable = 'on';
                    app.F1CheckBox.Enable = 'on';
                    app.ImF2.Enable = 'on';
                    app.F2CheckBox.Enable = 'on';
                case 3
                    app.ImF1.Enable = 'on';
                    app.F1CheckBox.Enable = 'on';
                    app.ImF2.Enable = 'on';
                    app.F2CheckBox.Enable = 'on';
                    app.ImF3.Enable = 'on';
                    app.F3CheckBox.Enable = 'on';
                case 4
                    app.ImF1.Enable = 'on';
                    app.F1CheckBox.Enable = 'on';
                    app.ImF2.Enable = 'on';
                    app.F2CheckBox.Enable = 'on';
                    app.ImF3.Enable = 'on';
                    app.F3CheckBox.Enable = 'on';
                    app.ImF4.Enable = 'on';
                    app.F4CheckBox.Enable = 'on';
                case 5
                    app.ImF1.Enable = 'on';
                    app.F1CheckBox.Enable = 'on';
                    app.ImF2.Enable = 'on';
                    app.F2CheckBox.Enable = 'on';
                    app.ImF3.Enable = 'on';
                    app.F3CheckBox.Enable = 'on';
                    app.ImF4.Enable = 'on';
                    app.F4CheckBox.Enable = 'on';
                    app.ImF5.Enable = 'on';
                    app.F5CheckBox.Enable = 'on';
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtPush(app, event)
            app.UIFigure.Visible = 'off';

            ApoSelect = [app.ApoSupCheckBox.Value;app.ApoInfCheckBox.Value];
            FlSelect = [app.F1CheckBox.Value;app.F2CheckBox.Value;app.F3CheckBox.Value
                app.F4CheckBox.Value;app.F5CheckBox.Value];

            % call main app's Confirm function
            ApoFlCorrectionFcn(app.callingapp,ApoSelect,FlSelect)

            delete(app)
        end

        % Close request function: UIFigure
        function CloseRequestFcn(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CorrectionsLabel = uilabel(app.GridLayout);
            app.CorrectionsLabel.Text = 'Corrections';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ConfirmButton = uibutton(app.GridLayout3, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtPush, true);
            app.ConfirmButton.Text = 'Confirm';
            app.AponeurosisPanel = uipanel(app.GridLayout);
            app.AponeurosisPanel.Title = 'Aponeurosis';
            app.GridLayout4 = uigridlayout(app.AponeurosisPanel);
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = 'CorrSupApo.png';
            app.Image2 = uiimage(app.GridLayout4);
            app.Image2.ImageSource = 'CorrInfApo.png';
            app.ApoSupCheckBox = uicheckbox(app.GridLayout4);
            app.ApoSupCheckBox.Text = '';
            app.ApoInfCheckBox = uicheckbox(app.GridLayout4);
            app.ApoInfCheckBox.Text = '';
            app.FasciclesPanel = uipanel(app.GridLayout);
            app.FasciclesPanel.Title = 'Fascicles';
            app.GridLayout5 = uigridlayout(app.FasciclesPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.ImF1 = uiimage(app.GridLayout6);
            app.ImF1.Enable = 'off';
            app.ImF1.ImageSource = 'CorrF1.png';
            app.ImF2 = uiimage(app.GridLayout6);
            app.ImF2.Enable = 'off';
            app.ImF2.ImageSource = 'CorrF2.png';
            app.ImF3 = uiimage(app.GridLayout6);
            app.ImF3.Enable = 'off';
            app.ImF3.ImageSource = 'CorrF3.png';
            app.F1CheckBox = uicheckbox(app.GridLayout6);
            app.F1CheckBox.Enable = 'off';
            app.F1CheckBox.Text = '';
            app.F2CheckBox = uicheckbox(app.GridLayout6);
            app.F2CheckBox.Enable = 'off';
            app.F2CheckBox.Text = '';
            app.F3CheckBox = uicheckbox(app.GridLayout6);
            app.F3CheckBox.Enable = 'off';
            app.F3CheckBox.Text = '';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.ImF4 = uiimage(app.GridLayout7);
            app.ImF4.Enable = 'off';
            app.ImF4.ImageSource = 'CorrF4.png';
            app.ImF5 = uiimage(app.GridLayout7);
            app.ImF5.Enable = 'off';
            app.ImF5.ImageSource = 'CorrF5.png';
            app.F4CheckBox = uicheckbox(app.GridLayout7);
            app.F4CheckBox.Enable = 'off';
            app.F4CheckBox.Text = '';
            app.F5CheckBox = uicheckbox(app.GridLayout7);
            app.F5CheckBox.Enable = 'off';
            app.F5CheckBox.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppCorrections(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end