classdef DialogAppCrop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        SaveButton                     matlab.ui.control.Button
        GridLayout5                    matlab.ui.container.GridLayout
        Height                         matlab.ui.control.NumericEditField
        HeightSizePixelEditFieldLabel  matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        Width                          matlab.ui.control.NumericEditField
        WidthSizePixelEditFieldLabel   matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        y                              matlab.ui.control.NumericEditField
        UpperLeftCorneryPositionEditFieldLabel  matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        x                              matlab.ui.control.NumericEditField
        UpperLeftCornerxPositionEditFieldLabel  matlab.ui.control.Label
        CropImageLabel                 matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, x, y, Width, Height)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;

            % Update Settings with input values
            app.x.Value = x;
            app.y.Value = y;
            app.Width.Value = Width;
            app.Height.Value = Height;
        end

        % Close request function: UIFigure
        function CloseRequestFcn(app, event)
            % Enable the Plot Options button in main app
            app.CallingApp.AutoCrop.Enable = 'on';

            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonFcn(app, event)
            % call main app's SaveSettings function
            SaveCrop(app.CallingApp,app.x.Value,app.y.Value,...
                app.Width.Value,app.Height.Value)

           CloseRequestFcn(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CropImageLabel = uilabel(app.GridLayout);
            app.CropImageLabel.Text = 'Crop Image ';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UpperLeftCornerxPositionEditFieldLabel = uilabel(app.GridLayout2);
            app.UpperLeftCornerxPositionEditFieldLabel.Text = 'Upper Left Corner (x Position) ';
            app.x = uieditfield(app.GridLayout2, 'numeric');
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UpperLeftCorneryPositionEditFieldLabel = uilabel(app.GridLayout3);
            app.UpperLeftCorneryPositionEditFieldLabel.Text = 'Upper Left Corner (y Position)';
            app.y = uieditfield(app.GridLayout3, 'numeric');
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.WidthSizePixelEditFieldLabel = uilabel(app.GridLayout4);
            app.WidthSizePixelEditFieldLabel.Text = 'Width Size (Pixel)';
            app.Width = uieditfield(app.GridLayout4, 'numeric');
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.HeightSizePixelEditFieldLabel = uilabel(app.GridLayout5);
            app.HeightSizePixelEditFieldLabel.Text = 'Height Size  (Pixel)';
            app.Height = uieditfield(app.GridLayout5, 'numeric');
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.SaveButton = uibutton(app.GridLayout6, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonFcn, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppCrop(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end