classdef DialogAppFascicles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        PartsOfFasciclePanel            matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        PartsofFascicle5DropDown        matlab.ui.control.DropDown
        PartsofFascicle5DropDownLabel   matlab.ui.control.Label
        PartsofFascicle4DropDown        matlab.ui.control.DropDown
        PartsofFascicle4DropDownLabel   matlab.ui.control.Label
        PartsofFascicle3DropDown        matlab.ui.control.DropDown
        PartsofFascicle3DropDownLabel   matlab.ui.control.Label
        PartsofFascicle2DropDown        matlab.ui.control.DropDown
        PartsofFascicle2DropDownLabel   matlab.ui.control.Label
        PartsofFascicle1DropDown        matlab.ui.control.DropDown
        PartsofFascicle1DropDownLabel   matlab.ui.control.Label
        FasciclesPanel                  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        NumberofFasciclesDropDown       matlab.ui.control.DropDown
        NumberofFasciclesDropDownLabel  matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        ConfirmButton                   matlab.ui.control.Button
        FasciclesLabel                  matlab.ui.control.Label
    end


    properties (Access = private)
        callingapp % Description
        NbFl
        FlParts
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, NbFl, FlParts)
            app.callingapp = mainapp;

            app.NumberofFasciclesDropDown.ItemsData = [1 2 3 4 5];
            app.PartsofFascicle1DropDown.ItemsData = [1 2 3 4 5];
            app.PartsofFascicle2DropDown.ItemsData = [1 2 3 4 5];
            app.PartsofFascicle3DropDown.ItemsData = [1 2 3 4 5];
            app.PartsofFascicle4DropDown.ItemsData = [1 2 3 4 5];
            app.PartsofFascicle5DropDown.ItemsData = [1 2 3 4 5];

            app.NumberofFasciclesDropDown.Value = NbFl;
            app.PartsofFascicle1DropDown.Value = FlParts(1);
            app.PartsofFascicle2DropDown.Value = FlParts(2);
            app.PartsofFascicle3DropDown.Value = FlParts(3);
            app.PartsofFascicle4DropDown.Value = FlParts(4);
            app.PartsofFascicle5DropDown.Value = FlParts(5);


            app.NbFl = NbFl;
            app.FlParts = FlParts;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtPush(app, event)
            app.UIFigure.Visible = 'off';

            app.NbFl = app.NumberofFasciclesDropDown.Value;
            app.FlParts(1) = app.PartsofFascicle1DropDown.Value;
            app.FlParts(2) = app.PartsofFascicle2DropDown.Value;
            app.FlParts(3) = app.PartsofFascicle3DropDown.Value;
            app.FlParts(4) = app.PartsofFascicle4DropDown.Value;
            app.FlParts(5) = app.PartsofFascicle5DropDown.Value;


            % call main app's Confirm function
            FlSelectFcn(app.callingapp,app.NbFl,app.FlParts,0)

            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: NumberofFasciclesDropDown
        function NbPartsFascicle(app, event)
            value = app.NumberofFasciclesDropDown.Value;
            assignin("base","value",value)
            switch value
                case 1
                    app.PartsofFascicle1DropDown.Visible = 'on';
                    app.PartsofFascicle1DropDownLabel.Visible = 'on';
                    app.PartsofFascicle2DropDown.Visible = 'off';
                    app.PartsofFascicle2DropDownLabel.Visible = 'off';
                    app.PartsofFascicle3DropDown.Visible = 'off';
                    app.PartsofFascicle3DropDownLabel.Visible = 'off';
                    app.PartsofFascicle4DropDown.Visible = 'off';
                    app.PartsofFascicle4DropDownLabel.Visible = 'off';
                    app.PartsofFascicle5DropDown.Visible = 'off';
                    app.PartsofFascicle5DropDownLabel.Visible = 'off';
                case 2
                    app.PartsofFascicle1DropDown.Visible = 'on';
                    app.PartsofFascicle1DropDownLabel.Visible = 'on';
                    app.PartsofFascicle2DropDown.Visible = 'on';
                    app.PartsofFascicle2DropDownLabel.Visible = 'on';
                    app.PartsofFascicle3DropDown.Visible = 'off';
                    app.PartsofFascicle3DropDownLabel.Visible = 'off';
                    app.PartsofFascicle4DropDown.Visible = 'off';
                    app.PartsofFascicle4DropDownLabel.Visible = 'off';
                    app.PartsofFascicle5DropDown.Visible = 'off';
                    app.PartsofFascicle5DropDownLabel.Visible = 'off';
                case 3
                    app.PartsofFascicle1DropDown.Visible = 'on';
                    app.PartsofFascicle1DropDownLabel.Visible = 'on';
                    app.PartsofFascicle2DropDown.Visible = 'on';
                    app.PartsofFascicle2DropDownLabel.Visible = 'on';
                    app.PartsofFascicle3DropDown.Visible = 'on';
                    app.PartsofFascicle3DropDownLabel.Visible = 'on';
                    app.PartsofFascicle4DropDown.Visible = 'off';
                    app.PartsofFascicle4DropDownLabel.Visible = 'off';
                    app.PartsofFascicle5DropDown.Visible = 'off';
                    app.PartsofFascicle5DropDownLabel.Visible = 'off';
                case 4
                    app.PartsofFascicle1DropDown.Visible = 'on';
                    app.PartsofFascicle1DropDownLabel.Visible = 'on';
                    app.PartsofFascicle2DropDown.Visible = 'on';
                    app.PartsofFascicle2DropDownLabel.Visible = 'on';
                    app.PartsofFascicle3DropDown.Visible = 'on';
                    app.PartsofFascicle3DropDownLabel.Visible = 'on';
                    app.PartsofFascicle4DropDown.Visible = 'on';
                    app.PartsofFascicle4DropDownLabel.Visible = 'on';
                    app.PartsofFascicle5DropDown.Visible = 'off';
                    app.PartsofFascicle5DropDownLabel.Visible = 'off';
                case 5
                    app.PartsofFascicle1DropDown.Visible = 'on';
                    app.PartsofFascicle1DropDownLabel.Visible = 'on';
                    app.PartsofFascicle2DropDown.Visible = 'on';
                    app.PartsofFascicle2DropDownLabel.Visible = 'on';
                    app.PartsofFascicle3DropDown.Visible = 'on';
                    app.PartsofFascicle3DropDownLabel.Visible = 'on';
                    app.PartsofFascicle4DropDown.Visible = 'on';
                    app.PartsofFascicle4DropDownLabel.Visible = 'on';
                    app.PartsofFascicle5DropDown.Visible = 'on';
                    app.PartsofFascicle5DropDownLabel.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FasciclesLabel = uilabel(app.GridLayout);
            app.FasciclesLabel.Text = 'Fascicles';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ConfirmButton = uibutton(app.GridLayout3, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtPush, true);
            app.ConfirmButton.Text = 'Confirm';
            app.FasciclesPanel = uipanel(app.GridLayout);
            app.FasciclesPanel.Title = 'Fascicles';
            app.GridLayout4 = uigridlayout(app.FasciclesPanel);
            app.NumberofFasciclesDropDownLabel = uilabel(app.GridLayout4);
            app.NumberofFasciclesDropDownLabel.Text = 'Number of Fascicles';
            app.NumberofFasciclesDropDown = uidropdown(app.GridLayout4);
            app.NumberofFasciclesDropDown.Items = {'1', '2', '3', '4', '5'};
            app.NumberofFasciclesDropDown.ValueChangedFcn = createCallbackFcn(app, @NbPartsFascicle, true);
            app.NumberofFasciclesDropDown.Value = '1';
            app.PartsOfFasciclePanel = uipanel(app.GridLayout);
            app.PartsOfFasciclePanel.Title = 'Parts Of Fascicle';
            app.GridLayout5 = uigridlayout(app.PartsOfFasciclePanel);
            app.PartsofFascicle1DropDownLabel = uilabel(app.GridLayout5);
            app.PartsofFascicle1DropDownLabel.Text = 'Parts of Fascicle 1';
            app.PartsofFascicle1DropDown = uidropdown(app.GridLayout5);
            app.PartsofFascicle1DropDown.Items = {'1', '2', '3', '4', '5'};
            app.PartsofFascicle1DropDown.Value = '1';
            app.PartsofFascicle2DropDownLabel = uilabel(app.GridLayout5);
            app.PartsofFascicle2DropDownLabel.Visible = 'off';
            app.PartsofFascicle2DropDownLabel.Text = 'Parts of Fascicle 2';
            app.PartsofFascicle2DropDown = uidropdown(app.GridLayout5);
            app.PartsofFascicle2DropDown.Items = {'1', '2', '3', '4', '5'};
            app.PartsofFascicle2DropDown.Visible = 'off';
            app.PartsofFascicle2DropDown.Value = '1';
            app.PartsofFascicle3DropDownLabel = uilabel(app.GridLayout5);
            app.PartsofFascicle3DropDownLabel.Visible = 'off';
            app.PartsofFascicle3DropDownLabel.Text = 'Parts of Fascicle 3';
            app.PartsofFascicle3DropDown = uidropdown(app.GridLayout5);
            app.PartsofFascicle3DropDown.Items = {'1', '2', '3', '4', '5'};
            app.PartsofFascicle3DropDown.Visible = 'off';
            app.PartsofFascicle3DropDown.Value = '1';
            app.PartsofFascicle4DropDownLabel = uilabel(app.GridLayout5);
            app.PartsofFascicle4DropDownLabel.Visible = 'off';
            app.PartsofFascicle4DropDownLabel.Text = 'Parts of Fascicle 4';
            app.PartsofFascicle4DropDown = uidropdown(app.GridLayout5);
            app.PartsofFascicle4DropDown.Items = {'1', '2', '3', '4', '5'};
            app.PartsofFascicle4DropDown.Visible = 'off';
            app.PartsofFascicle4DropDown.Value = '1';
            app.PartsofFascicle5DropDownLabel = uilabel(app.GridLayout5);
            app.PartsofFascicle5DropDownLabel.Visible = 'off';
            app.PartsofFascicle5DropDownLabel.Text = 'Parts of Fascicle 5';
            app.PartsofFascicle5DropDown = uidropdown(app.GridLayout5);
            app.PartsofFascicle5DropDown.Items = {'1', '2', '3', '4', '5'};
            app.PartsofFascicle5DropDown.Visible = 'off';
            app.PartsofFascicle5DropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppFascicles(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end