classdef DialogAppSave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        SelectthefascicledatatobesavedPanel  matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        GridLayout7                 matlab.ui.container.GridLayout
        F5CheckBox                  matlab.ui.control.CheckBox
        F4CheckBox                  matlab.ui.control.CheckBox
        ImF5                        matlab.ui.control.Image
        ImF4                        matlab.ui.control.Image
        GridLayout6                 matlab.ui.container.GridLayout
        F3CheckBox                  matlab.ui.control.CheckBox
        F2CheckBox                  matlab.ui.control.CheckBox
        F1CheckBox                  matlab.ui.control.CheckBox
        ImF3                        matlab.ui.control.Image
        ImF2                        matlab.ui.control.Image
        ImF1                        matlab.ui.control.Image
        SelectfileextensionPanel    matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        FileExtensionDropDown       matlab.ui.control.DropDown
        FileExtensionDropDownLabel  matlab.ui.control.Label
        GridLayout3                 matlab.ui.container.GridLayout
        SaveButton                  matlab.ui.control.Button
        SaveLabel                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        callingapp % Description
        NbFl
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, NbFl)
            app.callingapp = mainapp;

            app.NbFl = NbFl;

            switch NbFl
                case 1
                    app.ImF1.Enable = 'on';
                    app.F1CheckBox.Enable = 'on';
                    app.F1CheckBox.Value = 1;
                case 2
                    app.ImF1.Enable = 'on';
                    app.F1CheckBox.Enable = 'on';
                    app.F1CheckBox.Value = 1;
                    app.ImF2.Enable = 'on';
                    app.F2CheckBox.Enable = 'on';
                    app.F2CheckBox.Value = 1;
                case 3
                    app.ImF1.Enable = 'on';
                    app.F1CheckBox.Enable = 'on';
                    app.F1CheckBox.Value = 1;
                    app.ImF2.Enable = 'on';
                    app.F2CheckBox.Enable = 'on';
                    app.F2CheckBox.Value = 1;
                    app.ImF3.Enable = 'on';
                    app.F3CheckBox.Enable = 'on';
                    app.F3CheckBox.Value = 1;
                case 4
                    app.ImF1.Enable = 'on';
                    app.F1CheckBox.Enable = 'on';
                    app.F1CheckBox.Value = 1;
                    app.ImF2.Enable = 'on';
                    app.F2CheckBox.Enable = 'on';
                    app.F2CheckBox.Value = 1;
                    app.ImF3.Enable = 'on';
                    app.F3CheckBox.Enable = 'on';
                    app.F3CheckBox.Value = 1;
                    app.ImF4.Enable = 'on';
                    app.F4CheckBox.Enable = 'on';
                    app.F4CheckBox.Value = 1;
                case 5
                    app.ImF1.Enable = 'on';
                    app.F1CheckBox.Enable = 'on';
                    app.F1CheckBox.Value = 1;
                    app.ImF2.Enable = 'on';
                    app.F2CheckBox.Enable = 'on';
                    app.F2CheckBox.Value = 1;
                    app.ImF3.Enable = 'on';
                    app.F3CheckBox.Enable = 'on';
                    app.F3CheckBox.Value = 1;
                    app.ImF4.Enable = 'on';
                    app.F4CheckBox.Enable = 'on';
                    app.F4CheckBox.Value = 1;
                    app.ImF5.Enable = 'on';
                    app.F5CheckBox.Enable = 'on';
                    app.F5CheckBox.Value = 1;
            end
        end

        % Button pushed function: SaveButton
        function SaveButt(app, event)
            app.UIFigure.Visible = 'off';

            FlSelect = [app.F1CheckBox.Value;app.F2CheckBox.Value;app.F3CheckBox.Value
                app.F4CheckBox.Value;app.F5CheckBox.Value];
            for n = 1 : 5
                if FlSelect(n) == 0
                    FlSelect(n) = 1;
                else
                    FlSelect(n) = 0;
                end
            end
            
            % call main app's Confirm function
            ApoSaveFcn(app.callingapp,FlSelect,app.FileExtensionDropDown.Value)

            delete(app)
        end

        % Close request function: UIFigure
        function CloseRequestFcn(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SaveLabel = uilabel(app.GridLayout);
            app.SaveLabel.Text = 'Save';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SaveButton = uibutton(app.GridLayout3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButt, true);
            app.SaveButton.Text = 'Save';
            app.SelectfileextensionPanel = uipanel(app.GridLayout);
            app.SelectfileextensionPanel.Title = 'Select file extension :';
            app.GridLayout4 = uigridlayout(app.SelectfileextensionPanel);
            app.FileExtensionDropDownLabel = uilabel(app.GridLayout4);
            app.FileExtensionDropDownLabel.Text = 'File Extension';
            app.FileExtensionDropDown = uidropdown(app.GridLayout4);
            app.FileExtensionDropDown.Items = {'Excel (.xlsx)', 'CSV (.csv)', 'Matlab (.mat)'};
            app.FileExtensionDropDown.ItemsData = {'1', '2', '3'};
            app.FileExtensionDropDown.Value = '1';
            app.SelectthefascicledatatobesavedPanel = uipanel(app.GridLayout);
            app.SelectthefascicledatatobesavedPanel.Title = 'Select the fascicle data to be saved :';
            app.GridLayout5 = uigridlayout(app.SelectthefascicledatatobesavedPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.ImF1 = uiimage(app.GridLayout6);
            app.ImF1.Enable = 'off';
            app.ImF1.ImageSource = 'CorrF1.png';
            app.ImF2 = uiimage(app.GridLayout6);
            app.ImF2.Enable = 'off';
            app.ImF2.ImageSource = 'CorrF2.png';
            app.ImF3 = uiimage(app.GridLayout6);
            app.ImF3.Enable = 'off';
            app.ImF3.ImageSource = 'CorrF3.png';
            app.F1CheckBox = uicheckbox(app.GridLayout6);
            app.F1CheckBox.Enable = 'off';
            app.F1CheckBox.Text = '';
            app.F1CheckBox.Value = true;
            app.F2CheckBox = uicheckbox(app.GridLayout6);
            app.F2CheckBox.Enable = 'off';
            app.F2CheckBox.Text = '';
            app.F3CheckBox = uicheckbox(app.GridLayout6);
            app.F3CheckBox.Enable = 'off';
            app.F3CheckBox.Text = '';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.ImF4 = uiimage(app.GridLayout7);
            app.ImF4.Enable = 'off';
            app.ImF4.ImageSource = 'CorrF4.png';
            app.ImF5 = uiimage(app.GridLayout7);
            app.ImF5.Enable = 'off';
            app.ImF5.ImageSource = 'CorrF5.png';
            app.F4CheckBox = uicheckbox(app.GridLayout7);
            app.F4CheckBox.Enable = 'off';
            app.F4CheckBox.Text = '';
            app.F5CheckBox = uicheckbox(app.GridLayout7);
            app.F5CheckBox.Enable = 'off';
            app.F5CheckBox.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppSave(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end