classdef DialogAppScale < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ScaleLabel                     matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        SaveButton                     matlab.ui.control.Button
        GridLayout3                    matlab.ui.container.GridLayout
        DistMm                         matlab.ui.control.NumericEditField
        KnownDistanceinMilimeterLabel  matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        DistPxl                        matlab.ui.control.NumericEditField
        DistanceinPixelsLabel          matlab.ui.control.Label
        SetScaleLabel                  matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp % Description
        Scale
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, DistPxl, DistMm, Scale)

            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;

            % Update Settings with input values
            app.DistPxl.Value = DistPxl;
            app.DistMm.Value = DistMm;
            app.Scale = app.DistPxl.Value/app.DistMm.Value;

            if app.DistPxl.Value == 0
                app.ScaleLabel.Text = '';
            else
                app.ScaleLabel.Text = ['Scale : ',num2str(app.Scale),' pxl/mm'];
            end
            

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % call main app's Save Scale function
            SaveScale(app.CallingApp,app.DistPxl.Value,app.DistMm.Value,...
                app.Scale)

            delete(app)
        end

        % Value changed function: DistPxl
        function DistPxlChangedFcn(app, event)

            app.Scale = app.DistPxl.Value/app.DistMm.Value;

            app.ScaleLabel.Text = ['Scale : ',num2str(app.Scale),' pxl/mm'];
        end

        % Value changed function: DistMm
        function DistMmChangedFcn(app, event)

            app.Scale = app.DistPxl.Value/app.DistMm.Value;

            app.ScaleLabel.Text = ['Scale : ',num2str(app.Scale),' pxl/mm'];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SetScaleLabel = uilabel(app.GridLayout);
            app.SetScaleLabel.Text = 'Set Scale';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DistanceinPixelsLabel = uilabel(app.GridLayout2);
            app.DistanceinPixelsLabel.Text = 'Distance in Pixels';
            app.DistPxl = uieditfield(app.GridLayout2, 'numeric');
            app.DistPxl.ValueChangedFcn = createCallbackFcn(app, @DistPxlChangedFcn, true);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.KnownDistanceinMilimeterLabel = uilabel(app.GridLayout3);
            app.KnownDistanceinMilimeterLabel.Text = 'Known Distance in Milimeter';
            app.DistMm = uieditfield(app.GridLayout3, 'numeric');
            app.DistMm.ValueChangedFcn = createCallbackFcn(app, @DistMmChangedFcn, true);
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.SaveButton = uibutton(app.GridLayout6, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ScaleLabel = uilabel(app.GridLayout);
            app.ScaleLabel.Text = 'Scale: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppScale(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end