classdef DialogAppSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TrackingPanel                   matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        Hyperlink                       matlab.ui.control.Hyperlink
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        BlockSizeEditField              matlab.ui.control.NumericEditField
        BlockSizeEditFieldLabel         matlab.ui.control.Label
        MaxBidirectionalErrorEditField  matlab.ui.control.NumericEditField
        MaxBidirectionalErrorEditFieldLabel  matlab.ui.control.Label
        NumPyramidLevelsEditField       matlab.ui.control.NumericEditField
        NumPyramidLevelsEditFieldLabel  matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        SaveButton                      matlab.ui.control.Button
        DefaultSettingsButton           matlab.ui.control.Button
        BoundaryfascicleselectionPanel  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        LeftOrientation                 matlab.ui.control.CheckBox
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        RightOrientation                matlab.ui.control.CheckBox
        ImbinarizePanel                 matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        SensitivityEditField            matlab.ui.control.NumericEditField
        SensitivityEditFieldLabel       matlab.ui.control.Label
        SettingsLabel                   matlab.ui.control.Label
    end


    properties (Access = public)
    end

    properties (Access = private)
        % Default Settings
        SaveSettgs
        DefltSettgs = [0.5,1,0,3,Inf,7,30] % Each line corresponds at a setting, in the order of this apparition

        CallingApp   % Main app object
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, SaveSettgs)
            % Store main app in property for CloseRequestFcn to use
            app.SaveSettgs=SaveSettgs;
            app.CallingApp = mainapp;

            % Update Settings with input values
            app.SensitivityEditField.Value = SaveSettgs(1);
            app.RightOrientation.Value = SaveSettgs(2);
            app.LeftOrientation.Value = SaveSettgs(3);
            app.NumPyramidLevelsEditField.Value = SaveSettgs(4);
            app.MaxBidirectionalErrorEditField.Value = SaveSettgs(5);
            app.BlockSizeEditField.Value = SaveSettgs(6);
            app.MaxIterationsEditField.Value = SaveSettgs(7);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            app.SaveSettgs(1)= app.SensitivityEditField.Value;
            app.SaveSettgs(2)= app.RightOrientation.Value;
            app.SaveSettgs(3)= app.LeftOrientation.Value;
            app.SaveSettgs(4)= app.NumPyramidLevelsEditField.Value;
            app.SaveSettgs(5)= app.MaxBidirectionalErrorEditField.Value;
            app.SaveSettgs(6)= app.BlockSizeEditField.Value;
            app.SaveSettgs(7)= app.MaxIterationsEditField.Value;

            % call main app's SaveSettings function
            SaveSettings(app.CallingApp,app.SaveSettgs)

            delete(app)
        end

        % Callback function
        function DefaultSettingsButtonPushed(app, event)
            app.SensitivityEditField.Value = app.DefltSettgs(1);
            app.RightOrientation = app.DefltSettgs(2);
            app.LeftOrientation = app.DefltSettgs(3);
        end

        % Value changed function: RightOrientation
        function RightOrientationValChanged(app, event)
            value = app.RightOrientation.Value;

            if value==1
                app.LeftOrientation.Value = 0;
                app.RightOrientation.Value = 1;
            else
                app.LeftOrientation.Value = 1;
                app.RightOrientation.Value = 0;
            end
        end

        % Value changed function: LeftOrientation
        function LeftOrientationValChanged(app, event)
            value = app.LeftOrientation.Value;


            if value==1
                app.LeftOrientation.Value = 1;
                app.RightOrientation.Value = 0;
            else
                app.LeftOrientation.Value = 0;
                app.RightOrientation.Value = 1;
            end

        end

        % Value changed function: MaxIterationsEditField
        function MaxIterationsValChanged(app, event)
            value = app.MaxIterationsEditField.Value;
            if ~isfloat(value)
                fig = app.UIFigure;
                msg = 'MaxIterations value must be a integer ! Please, change your value.';
                title = 'Input Error';
                uiconfirm(fig,msg,title,...
                    'Icon','error');
                app.MaxIterationsEditField.Value = app.SaveSettgs(7);
            end
            app.MaxIterationsEditField.Value = round(app.MaxIterationsEditField.Value);
        end

        % Value changed function: BlockSizeEditField
        function BlockSizeValChanged(app, event)
            value = app.BlockSizeEditField.Value;
            if ~isfloat(value) || mod(value,2)==0
                fig = app.UIFigure;
                msg = 'BlockSize value must be odd integer ! Please, change your value.';
                title = 'Input Error';
                uiconfirm(fig,msg,title,...
                    'Icon','error');
                app.BlockSizeEditField.Value = app.SaveSettgs(6);
            end
            app.BlockSizeEditField.Value = round(app.BlockSizeEditField.Value);
        end

        % Value changed function: MaxBidirectionalErrorEditField
        function MaxBiErrorValChanged(app, event)
            value = app.MaxBidirectionalErrorEditField.Value;
            if ~isscalar(value)
                fig = app.UIFigure;
                msg = 'MaxBidirectionalError value must be a scalar ! Please, change your value.';
                title = 'Input Error';
                uiconfirm(fig,msg,title,...
                    'Icon','error');
                app.MaxBidirectionalErrorEditField.Value = app.SaveSettgs(5);
            end
            app.MaxBidirectionalErrorEditField.Value=round(app.MaxBidirectionalErrorEditField.Value);
        end

        % Value changed function: NumPyramidLevelsEditField
        function NumPyrLevValChanged(app, event)
            value = app.NumPyramidLevelsEditField.Value;
            if ~isfloat(value)
                fig = app.UIFigure;
                msg = 'NumPyramidLevels value must be a integer ! Please, change your value.';
                title = 'Input Error';
                uiconfirm(fig,msg,title,...
                    'Icon','error');
                app.NumPyramidLevelsEditField.Value = app.SaveSettgs(4);
            end
            app.NumPyramidLevelsEditField.Value=round(app.NumPyramidLevelsEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SettingsLabel = uilabel(app.GridLayout);
            app.SettingsLabel.Text = 'Settings';
            app.ImbinarizePanel = uipanel(app.GridLayout);
            app.ImbinarizePanel.Title = 'Imbinarize';
            app.GridLayout2 = uigridlayout(app.ImbinarizePanel);
            app.SensitivityEditFieldLabel = uilabel(app.GridLayout2);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity :';
            app.SensitivityEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SensitivityEditField.Limits = [0 1];
            app.SensitivityEditField.Value = 0.5;
            app.BoundaryfascicleselectionPanel = uipanel(app.GridLayout);
            app.BoundaryfascicleselectionPanel.Title = 'Boundary fascicle selection';
            app.GridLayout4 = uigridlayout(app.BoundaryfascicleselectionPanel);
            app.RightOrientation = uicheckbox(app.GridLayout4);
            app.RightOrientation.ValueChangedFcn = createCallbackFcn(app, @RightOrientationValChanged, true);
            app.RightOrientation.Text = '';
            app.RightOrientation.Value = true;
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = 'RightOrientation.png';
            app.Image2 = uiimage(app.GridLayout4);
            app.Image2.ImageSource = 'LeftOrientation.png';
            app.LeftOrientation = uicheckbox(app.GridLayout4);
            app.LeftOrientation.ValueChangedFcn = createCallbackFcn(app, @LeftOrientationValChanged, true);
            app.LeftOrientation.Text = '';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.DefaultSettingsButton = uibutton(app.GridLayout3, 'push');
            app.DefaultSettingsButton.Text = 'Default Settings';
            app.SaveButton = uibutton(app.GridLayout3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TrackingPanel = uipanel(app.GridLayout);
            app.TrackingPanel.Tooltip = {''};
            app.TrackingPanel.Title = 'Tracking';
            app.GridLayout5 = uigridlayout(app.TrackingPanel);
            app.NumPyramidLevelsEditFieldLabel = uilabel(app.GridLayout5);
            app.NumPyramidLevelsEditFieldLabel.Text = 'NumPyramidLevels';
            app.NumPyramidLevelsEditField = uieditfield(app.GridLayout5, 'numeric');
            app.NumPyramidLevelsEditField.Limits = [1 Inf];
            app.NumPyramidLevelsEditField.ValueDisplayFormat = '%.0f';
            app.NumPyramidLevelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumPyrLevValChanged, true);
            app.NumPyramidLevelsEditField.Tooltip = {'Must be integer'};
            app.NumPyramidLevelsEditField.Value = 3;
            app.MaxBidirectionalErrorEditFieldLabel = uilabel(app.GridLayout5);
            app.MaxBidirectionalErrorEditFieldLabel.Text = 'MaxBidirectionalError';
            app.MaxBidirectionalErrorEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MaxBidirectionalErrorEditField.Limits = [0 Inf];
            app.MaxBidirectionalErrorEditField.ValueDisplayFormat = '%.0f';
            app.MaxBidirectionalErrorEditField.ValueChangedFcn = createCallbackFcn(app, @MaxBiErrorValChanged, true);
            app.MaxBidirectionalErrorEditField.Tooltip = {'Must be integer'};
            app.MaxBidirectionalErrorEditField.Value = Inf;
            app.BlockSizeEditFieldLabel = uilabel(app.GridLayout5);
            app.BlockSizeEditFieldLabel.Text = 'BlockSize';
            app.BlockSizeEditField = uieditfield(app.GridLayout5, 'numeric');
            app.BlockSizeEditField.Limits = [1 Inf];
            app.BlockSizeEditField.ValueDisplayFormat = '%.0f';
            app.BlockSizeEditField.ValueChangedFcn = createCallbackFcn(app, @BlockSizeValChanged, true);
            app.BlockSizeEditField.Tooltip = {'Must be odd integer'};
            app.BlockSizeEditField.Value = 7;
            app.MaxIterationsEditFieldLabel = uilabel(app.GridLayout5);
            app.MaxIterationsEditFieldLabel.Text = 'MaxIterations';
            app.MaxIterationsEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MaxIterationsEditField.Limits = [1 Inf];
            app.MaxIterationsEditField.RoundFractionalValues = 'on';
            app.MaxIterationsEditField.ValueDisplayFormat = '%.0f';
            app.MaxIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxIterationsValChanged, true);
            app.MaxIterationsEditField.Tooltip = {'Must be integer'};
            app.MaxIterationsEditField.Value = 30;
            app.Hyperlink = uihyperlink(app.GridLayout5);
            app.Hyperlink.URL = 'https://fr.mathworks.com/help/vision/ref/vision.pointtracker-system-object.html';
            app.Hyperlink.Text = 'Click for help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppSettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end