classdef MuscleTrack_1_3_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UStrackFig                 matlab.ui.Figure
        Menu                       matlab.ui.container.Toolbar
        OpenPushMenu               matlab.ui.container.toolbar.PushTool
        SettingsPushMenu           matlab.ui.container.toolbar.PushTool
        SavePushMenu               matlab.ui.container.toolbar.PushTool
        GLUStrack                  matlab.ui.container.GridLayout
        GLplayer                   matlab.ui.container.GridLayout
        ActionDescription          matlab.ui.control.Label
        GridLayout                 matlab.ui.container.GridLayout
        ButtonCorrection           matlab.ui.control.Button
        TimeEditField              matlab.ui.control.NumericEditField
        Button                     matlab.ui.control.Button
        GLplayerParam              matlab.ui.container.GridLayout
        Slider                     matlab.ui.control.Slider
        Spinner                    matlab.ui.control.Spinner
        Label                      matlab.ui.control.Label
        UIAxesPlayer               matlab.ui.control.UIAxes
        GLsettings                 matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        FlTab                      matlab.ui.container.Tab
        GLfigFl                    matlab.ui.container.GridLayout
        UIAxesFigFl                matlab.ui.control.UIAxes
        FvTab                      matlab.ui.container.Tab
        GLfigFv                    matlab.ui.container.GridLayout
        UIAxesFigFv                matlab.ui.control.UIAxes
        MtTab                      matlab.ui.container.Tab
        GLfigMt                    matlab.ui.container.GridLayout
        UIAxesFigMt                matlab.ui.control.UIAxes
        PenTab                     matlab.ui.container.Tab
        GLfigPen                   matlab.ui.container.GridLayout
        UIAxesFigPen               matlab.ui.control.UIAxes
        DataTab                    matlab.ui.container.Tab
        GLtable                    matlab.ui.container.GridLayout
        UITable                    matlab.ui.control.Table
        Step2AnalysisPanel         matlab.ui.container.Panel
        GLbutton2                  matlab.ui.container.GridLayout
        Run                        matlab.ui.control.Button
        ApoInf                     matlab.ui.control.Button
        Fascicle                   matlab.ui.control.Button
        ApoSup                     matlab.ui.control.Button
        Step1ImageProcessingPanel  matlab.ui.container.Panel
        GLbutton                   matlab.ui.container.GridLayout
        ManuCrop                   matlab.ui.control.Button
        ScaleBtn                   matlab.ui.control.Button
        ScalepxlmmEditFieldLabel   matlab.ui.control.Label
        ScalepxlmmEditField        matlab.ui.control.NumericEditField
        AutoCrop                   matlab.ui.control.Button
    end


    properties (Access = private)
        %         IconPath = addpath(fullfile(pwd,"Icon")) % Description
    end

    properties (Access = public)
        %% DialogApp
        DialogAppSet
        DialogAppCropAuto
        DialogAppApo
        DialogAppScaleData
        DialogAppFl
        DialogAppCorr
        DialogAppSaveDAT
        DialogAppCorrParts

        %% Settings Properties
        SaveSettgs = [0.5,1,0,3,Inf,7,30]


        %% Aponevris Parts
        nbPartsApo = [0;0]
        SupInfApo
        Apo = struct

        %% Fascicle Nulber and Parts
        NbFl = 1
        FlParts = [1 1 1 1 1]
        Fl = struct
        FlnCorr
        FlCorr = 0 ;
        ApoSupCorr = 0;
        ApoInfCorr = 0;
        Corr = 0;
        ApoInfPos
        ApoSupPos
        FlPos

        %% Crop Settings
        x = 0
        y = 0
        Width
        Height

        %% Scale settings
        DistPxl = 0
        DistMm = 0
        Scale = 1

        %% Figure
        dcm_obj
        lineplot
        dataplot
        CurrFramePlotFl = gobjects(1,1);
        CurrFramePlotPen = gobjects(1,1);
        CurrFramePlotFv = gobjects(1,1);
        CurrFramePlotMt = gobjects(1,1);

        %% Video
        frame % Video frames
        nbFrame = 1000 % Number of frames
        frameRate
        time
        frameBW % Video frames in Black and White
        liveIM %
        BWorColor % Color of the current image
    end

    methods (Access = public)

        function  SaveSettings(app,SaveSettgs)
            app.SaveSettgs = SaveSettgs;
        end

        function SaveCrop(app,x,y,Width,Height)
            app.x = x ;
            app.y = y ;
            app.Width = Width ;
            app.Height = Height ;

            for n = 1:app.nbFrame
                app.frame{n} = imcrop(app.frame{n},[app.x,app.y,app.Width,app.Height]);
            end
            app.BWorColor = "Color";
            app.liveIM = plotLiveFrame(app, app.frame, app.Spinner.Value) ;
            app.Button.Visible = 'off';
        end

        function SaveScale(app,DistPxl,DistMm,Scale)
            app.DistPxl = DistPxl;
            app.DistMm = DistMm;
            app.Scale = Scale;

            app.ScalepxlmmEditField.Value=app.Scale;
            delete(app.lineplot(:))
            delete(app.dataplot(:))
        end

        function ApoSelectFcn(app,nbPartsApo)
            app.ActionDescription.Visible='on';
            app.Button.Visible = 'on';

            %Preallocate
            xy = zeros(2,2);
            switch app.SupInfApo
                case "Superior"
                    app.nbPartsApo(1,1) = nbPartsApo;
                    SupApoBoundary = cell(app.nbPartsApo(1,1));
                case "Inferior"
                    app.nbPartsApo(2,1) = nbPartsApo;
                    InfApoBoundary = cell(app.nbPartsApo(2,1));
            end

            % Select data point of part of Aponeurosis

            switch app.SupInfApo
                case "Superior"
                    for i = 1:app.nbPartsApo(1,1)
                        for n= 1:2
                            switch n
                                case 1
                                    app.ActionDescription.Text = ['Pick a point on <u><b style="color:rgb(1,33,33);">THE BOUNDARY</b></u> of the ',...
                                        '<b style="color:rgb(0,189,94);">INFERIOR LEFT</b> part of the aponeurosis.'] ;
                                case 2
                                    app.ActionDescription.Text = ['Pick a point of the ',...
                                        '<b style="color:rgb(0,189,94);">INFERIOR RIGHT</b> part of the aponeurosis.'] ;
                            end
                            % Get Data Cursor Info and plot it.
                            [xy(n,:),app.dataplot(n)] = GetDataTips(app,'+',10,'r',1.5);
                        end
                        % Get a point in boundary
                        if (app.frameBW{app.Spinner.Value}(xy(1,2),xy(1,1))==0)
                            while(app.frameBW{app.Spinner.Value}(xy(1,2),xy(1,1))==0)
                                xy(1,2) = xy(1,2) - 1;
                            end
                        else
                            while(app.frameBW{app.Spinner.Value}(xy(1,2) + 1,xy(1,1))==1)
                                xy(1,2) = xy(1,2) + 1;
                            end    
                        end
                        % Calcule distance between two pick point
                        DistApo = round(norm(xy(1,:)-xy(2,:))); % The distance between two points is given by the vector norm from these two points

                        SupApoBoundary{i} = bwtraceboundary(app.frameBW{app.Spinner.Value},...
                            [xy(1,2) xy(1,1)], 'E', 8, DistApo,'counterclockwise'); %Ap Sup

                        delete(app.dataplot(:))
                        plot(app.UIAxesPlayer,SupApoBoundary{i}(:,2),SupApoBoundary{i}(:,1),':','color',[1 0 0 0.8],'LineWidth',1.5,'PickableParts',"none");

                        app.ApoSupPos = cat(1,SupApoBoundary{:}); % Concatenate all Part of Aponeurosis
                        app.ApoSupPos = flip(app.ApoSupPos,2); % Rearrange the order of the matrix to be compatible with the Vision.PointTracker function
                    end


                case "Inferior"
                    for i = 1:app.nbPartsApo(2,1)
                        for n= 1:2
                            switch n
                                case 1
                                    app.ActionDescription.Text = ['Pick a point on <u><b style="color:rgb(1,33,33);">THE BOUNDARY</b></u> of the ',...
                                        '<b style="color:rgb(0,189,94);">SUPERIOR LEFT</b> part of the aponeurosis.'] ;
                                case 2
                                    app.ActionDescription.Text = ['Pick a point of the ',...
                                        '<b style="color:rgb(0,189,94);">SUPERIOR RIGHT</b> part of the aponeurosis.'] ;

                            end
                            % Get Data Cursor Info and plot it.
                            [xy(n,:),app.dataplot(n)] = GetDataTips(app,'+',10,'r',1.5);
                        end
                        % Get a point in boundary
                        if (app.frameBW{app.Spinner.Value}(xy(1,2),xy(1,1))==0)
                            while(app.frameBW{app.Spinner.Value}(xy(1,2),xy(1,1))==0)
                                xy(1,2) = xy(1,2) + 1;
                            end
                        else
                            while(app.frameBW{app.Spinner.Value}(xy(1,2) - 1,xy(1,1))==1)
                                xy(1,2) = xy(1,2) - 1;
                            end
                        end
                        % Calcule distance between two pick point
                        DistApo = round(norm(xy(1,:)-xy(2,:))); % The distance between two points is given by the vector norm from these two points
                        InfApoBoundary{i} = bwtraceboundary(app.frameBW{app.Spinner.Value},...
                            [xy(1,2) xy(1,1)], 'E', 8, DistApo,'clockwise'); %Ap Inf

                        delete(app.dataplot(:))
                        plot(app.UIAxesPlayer,InfApoBoundary{i}(:,2),InfApoBoundary{i}(:,1),':','color',[1 0 0 0.8],'LineWidth',1.5,'PickableParts',"none");

                        app.ApoInfPos = cat(1,InfApoBoundary{:});
                        app.ApoInfPos = flip(app.ApoInfPos,2);
                    end
            end
            app.ActionDescription.Visible='off';
            app.Button.Visible = 'off';
        end

        function FlSelectFcn(app,NbFl,FlParts,Correction)
            app.NbFl=NbFl;
            app.FlParts = FlParts;
            app.ActionDescription.Interpreter = 'none';
            app.ActionDescription.Visible = 'on';
            app.ActionDescription.FontWeight = 'bold';
            app.ActionDescription.FontColor = [0,0.69,0.94];
            app.Button.Visible = 'on';

            if Correction==1 %% Correction Settings
                NbFlCorr = app.FlnCorr;
                StartFl = app.FlnCorr;
            else %% Default Settings
                StartFl = 1;
                NbFlCorr = app.NbFl;
            end

            %Preallocate
            xy = zeros(2,2);
            FlBoundary = cell(1,NbFlCorr);

            % Select data point of part of Fascicle
            for fl = StartFl:NbFlCorr
                FlBoundary(:) = {cell(1,app.FlParts(fl))};
                for p = 1:app.FlParts(fl)

                    app.ActionDescription.Text = "Fascicle "+fl+"/"+NbFlCorr+" - Part "+p+"/"+app.FlParts(fl);
                    for n=1:2
                        [xy(n,:),app.dataplot(n)] = GetDataTips(app,'+',10,[0,0.69,0.94],1.5);
                    end
                    % Get a point in boundary                    
                    if (app.frameBW{app.Spinner.Value}(xy(1,2),xy(1,1))==0)
                        while(app.frameBW{app.Spinner.Value}(xy(1,2),xy(1,1))==0)
                            xy(1,2) = xy(1,2) + 1;
                        end
                    else
                        while(app.frameBW{app.Spinner.Value}(xy(1,2) - 1,xy(1,1))==1 )
                            xy(1,2) = xy(1,2) - 1;
                            xy(2,2) = xy(2,2) + 1;
                        end
                    end

                    % Calcule distance between two pick point
                    DistFl = round(norm(xy(1,:)-xy(2,:))); % The distance between two points is given by the vector norm from these two points

                    % Boundary Detector
                    switch app.SaveSettgs(2)
                        case 1

                            if app.frameBW{app.Spinner.Value}(xy(1,2)-1,xy(1,1)+1)==0 % If the pixel to the top of the selected point is black then "..."
                                Direction = 'clockwise';
                            else
                                Direction = 'counterclockwise';
                            end

                            FlBoundary{fl}{p} = bwtraceboundary(app.frameBW{app.Spinner.Value},...
                                [xy(1,2) xy(1,1)], 'E', 8, DistFl,Direction); %Right Orientation

                            delete(app.dataplot(:))
                            plot(app.UIAxesPlayer,FlBoundary{fl}{p}(:,2),FlBoundary{fl}{p}(:,1),':','Color',[0,0.69,0.94,0.8],'LineWidth',1.5,'PickableParts',"none");

                        case 2

                            if app.frameBW{app.Spinner.Value}(xy(1,2)-1,xy(1,1)+1)==0 % If the pixel to the right of the selected point is black then "..."
                                Direction = 'counterclockwise';
                            else
                                Direction = 'clockwise';
                            end
                            FlBoundary{fl}{p} = bwtraceboundary(app.frameBW{app.Spinner.Value},...
                                [xy(1,2) xy(1,1)], 'W', 8, DistFl,Direction); %Left Orientation

                            delete(app.dataplot(:))
                            plot(app.UIAxesPlayer,FlBoundary{fl}{p}(:,2),FlBoundary{fl}{p}(:,1),':','Color',[0,0.69,0.94,0.8],'LineWidth',1.5,'PickableParts',"none");
                    end
                end
                app.FlPos{fl} = cat(1,FlBoundary{fl}{:});
                app.FlPos{fl} = flip(app.FlPos{fl},2);
            end
            app.ActionDescription.Interpreter = 'html';
            app.ActionDescription.Visible = 'off';
            app.ActionDescription.FontWeight = 'normal';
            app.ActionDescription.FontColor = 'k';
            app.Button.Visible = 'off';
        end

        function ApoFlCorrectionFcn(app,ApoSelect,FlSelect)
            app.dcm_obj.Enable='on';

            % Correction Aponevrose
            if ApoSelect(1,1)==1
                app.SupInfApo = "Superior";
                app.ApoSupCorr = 1;
                app.DialogAppCorrParts = DialogAppCorrectionPartsApoFl(app,app.SupInfApo);
                while isvalid(app.DialogAppCorrParts)
                    pause(0.1);
                end
                ApoSelectFcn(app,app.nbPartsApo(1,1))
            end

            if ApoSelect(2,1)==1
                app.SupInfApo = "Inferior";
                app.ApoInfCorr = 1;
                app.DialogAppCorrParts = DialogAppCorrectionPartsApoFl(app,app.SupInfApo);
                while isvalid(app.DialogAppCorrParts)
                    pause(0.1);
                end
                ApoSelectFcn(app,app.nbPartsApo(2,1))
            end

            % Correction Fascicle
            for n=1:size(FlSelect,1)
                if FlSelect(n) == 1
                    app.FlnCorr = n;
                    app.FlCorr = 1;
                    app.DialogAppCorrParts = DialogAppCorrectionPartsApoFl(app,n);
                    while isvalid(app.DialogAppCorrParts)
                        pause(0.1);
                    end
                    FlSelectFcn(app,app.NbFl,app.FlParts,1)
                end
            end
            RunButtPush(app)
            app.FlCorr=0;
        end

        function ApoSaveFcn(app,FlSelect,SaveExtension)
            SaveDAT = app.UITable.Data;
            
            SaveDAT.(2)(:, FlSelect(1 : app.NbFl)) = [];
            SaveDAT.(3)(:, FlSelect(1 : app.NbFl)) = [];
            SaveDAT.(4)(:, FlSelect(1 : app.NbFl)) = [];
            SaveDAT.(6)(:, FlSelect(1 : app.NbFl)) = [];
            SaveDAT.(7)(:, FlSelect(1 : app.NbFl)) = [];
            SaveDAT.(8)(:, FlSelect(1 : app.NbFl)) = [];
            SaveDAT.(9)(:, FlSelect(1 : app.NbFl)) = [];

            switch SaveExtension
                case '1'
                    [file, path] = uiputfile('.xlsx');
                    if file==0
                        return
                    end
                    writetable(SaveDAT,fullfile(path,file),'PreserveFormat',true)
                case '2'
                    [file, path] = uiputfile('.csv');
                    if file==0
                        return
                    end
                    writetable(SaveDAT,fullfile(path,file),'Delimiter',',','QuoteStrings',true)
                case '3'
                    [file, path] = uiputfile('.mat');
                    if file==0
                        return
                    end
                    save(fullfile(path,file),'SaveDAT');
            end

        end


        function CorrectionPartsApoFlFcn(app, ApoFlSelected, nbParts)
            if isstring(ApoFlSelected)
                switch ApoFlSelected
                    case "Superior"
                        app.nbPartsApo(1,1) = nbParts;
                    case "Inferior"
                        app.nbPartsApo(2,1) = nbParts;
                end
            else
                app.FlParts(ApoFlSelected) = nbParts;
            end
        end

    end

    methods (Access = private)

        function liveIM = plotLiveFrame(app,frame, numFrame)

            hold(app.UIAxesPlayer,"off")
            app.UIAxesPlayer.XLim = [0 app.Width];
            app.UIAxesPlayer.YLim = [0 app.Height];
            liveIM = imshow(frame{numFrame} , 'Parent', app.UIAxesPlayer);

            hold(app.UIAxesPlayer,"on")

            if ~isempty(app.UITable.Data)
                plot(app.UIAxesPlayer,1:app.Width,app.Apo.lineSup{numFrame}(:),'color',[1 0 0 0.7],'LineWidth',2,'PickableParts',"none")
                plot(app.UIAxesPlayer,1:app.Width,app.Apo.lineInf{numFrame}(:),'color',[1 0 0 0.7],'LineWidth',2,'PickableParts',"none")
                for fl = 1:app.NbFl
                    plot(app.UIAxesPlayer,[app.Fl(fl).IntersectSup(numFrame,1),app.Fl(fl).IntersectInf(numFrame,1)],...
                        [app.Fl(fl).IntersectSup(numFrame,2),app.Fl(fl).IntersectInf(numFrame,2)],':o','Color',[0 1 0 0.7],'LineWidth',3, ...
                        'MarkerSize',5,...
                        'MarkerEdgeColor','g',...
                        'MarkerFaceColor','r',...
                        'PickableParts',"none")
                end
                set(app.CurrFramePlotFl,'Value',app.time(numFrame))
                set(app.CurrFramePlotFv,'Value',app.time(numFrame))
                set(app.CurrFramePlotPen,'Value',app.time(numFrame))
                set(app.CurrFramePlotMt,'Value',app.time(numFrame))
            end
            %             drawnow limitrate

        end


        function [xy,dataplot] = GetDataTips(app,Marker,Msize,Color,LineWidth)
            uiwait(app.UStrackFig)
            % Export cursor to workspace
            info_struct = getCursorInfo(app.dcm_obj);

            xy(1,1)=info_struct.Position(1,1);
            xy(1,2)=info_struct.Position(1,2);

            dataplot=plot(app.UIAxesPlayer,xy(1,1),xy(1,2),Marker,'Color',Color,'MarkerSize',Msize,'LineWidth',LineWidth);

            dt = findobj(app.liveIM,'Type','datatip');
            delete(dt)
        end

        function Angle = AngleTrack(~,EqL1,EqL2)
            % Step 1 : Vector Creation
            v1 = [1 EqL1(1,1)]; %EqL1(1,1) is the slope of the equation
            v2 = [1 EqL2(1,1)];

            %Step 2 : Norm of Vector
            v1Norm = norm(v1);
            v2Norm = norm(v2);

            %Step 3 : Dot Product
            DotProduct = dot(v1,v2);

            %Step 4 : Angle Calculation
            Angle = acosd(DotProduct/(v1Norm*v2Norm));
        end

        function CoInt = CoordIntersec(~,FlEql,ApoEql)

            %Step 1 : Create Matrix form of linear equation
            CoefMat = [ApoEql(1,1) , -1 ; FlEql(1,1) , -1]; % Apo Sup
            ConstMat = [-ApoEql(1,2) ; -FlEql(1,2)];

            %Step 2 : Decomposition to solves linear equation
            CoInt(1,:) =  CoefMat\ConstMat ;

        end

        function PlotResults(app)
            cla(app.UIAxesFigFl)
            cla(app.UIAxesFigFv)
            cla(app.UIAxesFigPen)
            cla(app.UIAxesFigMt)

            Leg=cell(app.NbFl,1);

            for fl = 1: app.NbFl
                Leg{fl} = "Fascicle "+fl;
            end

            app.TabGroup.Visible = 'on';

            app.UIAxesFigFl.Title.String = 'Fascicle Lenght';
            app.UIAxesFigFl.YLabel.String = 'Fl (mm)';
            app.UIAxesFigFl.XLabel.String = 'Time (s)';

            app.UIAxesFigFv.Title.String = 'Fascicle Velocity';
            app.UIAxesFigFv.YLabel.String = 'Fl (mm/s)';
            app.UIAxesFigFv.XLabel.String = 'Time (s)';

            app.UIAxesFigPen.Title.String = 'Pennation Angle';
            app.UIAxesFigPen.YLabel.String = '𝛼 (°)';
            app.UIAxesFigPen.XLabel.String = 'Time (s)';

            app.UIAxesFigMt.Title.String = 'Muscle Thickness';
            app.UIAxesFigMt.YLabel.String = 'Mt (mm)';
            app.UIAxesFigMt.XLabel.String = 'Time (s)';

            hold(app.UIAxesFigFl,'on')
            hold(app.UIAxesFigFv,'on')
            hold(app.UIAxesFigPen,'on')
            hold(app.UIAxesFigMt,'on')

            for fl =1: app.NbFl
                plot(app.UIAxesFigFl,app.time,app.Fl(fl).Lenght)
                plot(app.UIAxesFigFv,app.time,app.Fl(fl).Velocity)
                plot(app.UIAxesFigPen,app.time,app.Fl(fl).InfAngle)
            end
            plot(app.UIAxesFigMt,app.time,app.Apo.MuscleThickness)
            app.CurrFramePlotFl = xline(app.UIAxesFigFl,0,'DisplayName','Current Frame');
            app.CurrFramePlotFv = xline(app.UIAxesFigFv,0,'DisplayName','Current Frame');
            app.CurrFramePlotPen = xline(app.UIAxesFigPen,0,'DisplayName','Current Frame');
            app.CurrFramePlotMt = xline(app.UIAxesFigMt,0,'DisplayName','Current Frame');

            legend(app.UIAxesFigFl,Leg)
            legend(app.UIAxesFigFv,Leg)
            legend(app.UIAxesFigPen,Leg)

            hold(app.UIAxesFigFl,'off')
            hold(app.UIAxesFigFv,'off')
            hold(app.UIAxesFigPen,'off')
            hold(app.UIAxesFigMt,'off')
        end

        function TabCreation(app)
            %Prealocate
            FLenght = zeros(size(app.time,1),app.NbFl);
            FVel = zeros(size(app.time,1),app.NbFl);
            FPen = zeros(size(app.time,1),app.NbFl);
            FintersecInf_x = zeros(size(app.time,1),app.NbFl);
            FintersecInf_y = zeros(size(app.time,1),app.NbFl);
            FintersecSup_x = zeros(size(app.time,1),app.NbFl);
            FintersecSup_y = zeros(size(app.time,1),app.NbFl);

            FMT = app.Apo.MuscleThickness;
            Time = app.time ;
            for fl=1:app.NbFl
                FLenght(:,fl) = app.Fl(fl).Lenght;
                FVel(:,fl) =  app.Fl(fl).Velocity;
                FPen(:,fl) = app.Fl(fl).InfAngle;
                FintersecInf_x(:,fl) = app.Fl(fl).IntersectInf(:,1);
                FintersecInf_y(:,fl) = app.Fl(fl).IntersectInf(:,2);
                FintersecSup_x(:,fl) = app.Fl(fl).IntersectSup(:,1);
                FintersecSup_y(:,fl) = app.Fl(fl).IntersectSup(:,2);
            end

            app.UITable.Data=table(Time,FLenght,FVel,FPen,FMT,...
                FintersecInf_x,FintersecInf_y,FintersecSup_x,FintersecSup_y,...
                'VariableNames',{'t (s)','Fl (mm)','Fv (mm/s)','𝛼 (°)',...
                'Mt (mm)','Intersec_inf_x','Intersec_inf_y',...
                'Intersec_sup_x','Intersec_sup_y'});

        end

        function PreAllocateRun(app,StartFl,NbFlCorr)
            %Pre allocate Struct
            app.Fl = struct('Track',cell(1,NbFlCorr),'EqLin',cell(1,NbFlCorr),'SupAngle',cell(1,NbFlCorr),'InfAngle',...
                cell(1,NbFlCorr),'IntersectSup',cell(1,NbFlCorr),'IntersectInf',cell(1,NbFlCorr),...
                'Lenght',cell(1,NbFlCorr),'Velocity',cell(1,NbFlCorr),...
                'EqLinInfSubSlope',cell(1,NbFlCorr),'line',cell(1,NbFlCorr));

            app.Apo = struct('SupTrack',cell(1,1),'EqLinSup',cell(1,1),...
                'InfTrack',cell(1,1),'EqLinInf',cell(1,1),'EqLinInfSubSlope',cell(1,1),...
                'LineSup',cell(1,1),'LineInf',cell(1,1),'MuscleThickness',cell(1,1));

            % Pre allocate Aponevrose Field
            app.Apo.EqLinSup = zeros(app.nbFrame,2);
            app.Apo.EqLinInf = zeros(app.nbFrame,2);
            app.Apo.lineSup = cell(1,app.nbFrame);
            app.Apo.lineSup(:) = {zeros(1,app.Width)};
            app.Apo.lineInf = cell(1,app.nbFrame);
            app.Apo.lineInf(:) = {zeros(1,app.Width)};
            app.Apo.MuscleThickness = zeros(app.nbFrame,1);

            % Pre allocate Fascile Field
            for fl=StartFl:NbFlCorr
                app.Fl(fl).EqLin = zeros(app.nbFrame,2);
                app.Fl(fl).SupAngle = zeros(app.nbFrame,1);
                app.Fl(fl).InfAngle = zeros(app.nbFrame,1);
                app.Fl(fl).IntersectSup = zeros(app.nbFrame,2);
                app.Fl(fl).IntersectInf = zeros(app.nbFrame,2);
                app.Fl(fl).Lenght = zeros(app.nbFrame,1);
                app.Fl(fl).Velocity = zeros(app.nbFrame,1);
                app.Fl(fl).line = cell(1,app.nbFrame);
                app.Fl(fl).line(:) = {zeros(1,app.Width)};

            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dcm_obj = datacursormode(app.UStrackFig);
            app.dcm_obj.UpdateFcn = @myfunction;
        end

        % Value changing function: Slider
        function SliderValChanging(app, event)
            changingValue = round(event.Value);
            app.Spinner.Value = changingValue;

            if app.BWorColor == "BlackAndWhite"
                app.liveIM = plotLiveFrame(app, app.frameBW, app.Spinner.Value) ;
            else
                app.liveIM = plotLiveFrame(app, app.frame, app.Spinner.Value) ;
            end

            app.TimeEditField.Value = app.time(app.Spinner.Value);
        end

        % Value changed function: Spinner
        function SpinnerValChanged(app, event)
            value = app.Spinner.Value;
            app.Slider.Value = value;

            if app.BWorColor == "BlackAndWhite"
                app.liveIM = plotLiveFrame(app, app.frameBW, app.Spinner.Value) ;
            else
                app.liveIM = plotLiveFrame(app, app.frame, app.Spinner.Value) ;
            end

            app.TimeEditField.Value = app.time(app.Spinner.Value);
        end

        % Button pushed function: ManuCrop
        function ManuCropVid(app, event)

            app.ActionDescription.Visible='on';
            app.Button.Visible = 'on';
            % Preallocate
            xy = zeros(2,2);
            for i=1:2
                switch i
                    case 1
                        app.ActionDescription.Text = ['Select the <b style="color:rgb(1,33,33);">UPPER LEFT corner</b> ',...
                            'of the area you wish to clip.'] ;
                    case 2
                        app.ActionDescription.Text = ['Select the <b style="color:rgb(1,33,33);">BOTTOM RIGHT corner</b> ',...
                            'of the area you wish to clip.'] ;
                end

                [xy(i,:),app.dataplot(i)] = GetDataTips(app,'+',20,'r',3);
                app.Height = abs(xy(1,2)-xy(2,2));
                app.Width = abs(xy(1,1)-xy(2,1));
            end
            for n = 1:app.nbFrame
                app.frame{n} = imcrop(app.frame{n},[xy(1,1),xy(1,2),xy(2,1)-xy(1,1),xy(2,2)-xy(1,2)]);
            end
            app.BWorColor = "color";
            app.liveIM = plotLiveFrame(app,app.frame, app.Spinner.Value) ;
            app.ActionDescription.Visible='off';
            app.Button.Visible = 'off';
        end

        % Button pushed function: Button
        function ContinueFcn(app, event)
            uiresume(app.UStrackFig)
        end

        % Button pushed function: AutoCrop
        function AutoCropVid(app, event)
            % Disable Plot Options button while dialog is open
            app.AutoCrop.Enable = 'off';

            % Open the settings dialog and pass inputs
            app.DialogAppCropAuto = DialogAppCrop(app, app.x, app.y, app.Width, app.Height);
        end

        % Button pushed function: ApoSup
        function ApoSupButtPush(app, event)
            app.Run.Enable = 'on';
            app.ApoInf.Enable = 'on';
            app.Fascicle.Enable = 'on';

            % Ui progresse dlg bar
            d = uiprogressdlg(app.UStrackFig,'Title','Loading...',...
                'Message','Please wait.','Indeterminate','on','Cancelable','on');
            drawnow

            % Transform frame into BW logical matrix
            app.frameBW = cell(1,app.nbFrame); %Preallocate
            app.frameBW(:) = {false(app.Height,app.Width)}; %Preallocate

            for n = 1:app.nbFrame
                frameGray = rgb2gray(app.frame{n});
                app.frameBW{n} = imbinarize(frameGray,'adaptive','ForegroundPolarity','bright','Sensitivity',app.SaveSettgs(1));
            end
            app.BWorColor = "BlackAndWhite";
            app.liveIM = plotLiveFrame(app, app.frameBW, app.Spinner.Value) ;
            close(d)
            % Open the Apo dialog and pass inputs
            app.SupInfApo = "Superior";
            app.DialogAppApo = DialogAppAponeurosis(app,app.SupInfApo);
        end

        % Close request function: UStrackFig
        function UStrackFigCloseRequest(app, event)
            delete(app.DialogAppSet)
            delete(app.DialogAppCropAuto)
            delete(app.DialogAppApo)
            delete(app.DialogAppScaleData)
            delete(app.DialogAppFl)
            delete(app.DialogAppCorr)
            delete(app.DialogAppCorrParts)
            delete(app)
        end

        % Key press function: UStrackFig
        function KeyPressFcn(app, event)
            key = event.Key;
            value = app.Spinner.Value; %Get the slider value
            switch key
                case 'leftarrow'
                    if value ~= app.Spinner.Limits(1)
                        value = value-1;
                    end
                case 'rightarrow'
                    if value ~= app.Spinner.Limits(2)
                        value = value+1;
                    end
            end
            app.Spinner.Value=value; %Set the slider value
            SpinnerValChanged(app, event)
        end

        % Button pushed function: ScaleBtn
        function ScaleButtPush(app, event)
            app.ActionDescription.Visible ='on';
            app.Button.Visible = 'on';
            xy = zeros(2,2);
            for i=1:2
                switch i
                    case 1
                        app.ActionDescription.Text = ['Select the <b style="color:rgb(0,189,94);"> FIRST COORDINATE </b> ',...
                            'of a known distance. '] ;
                    case 2
                        app.ActionDescription.Text = ['Select the <b style="color:rgb(0,189,94);"> SECOND COORDINATE </b>',...
                            'of a known distance. '] ;
                end

                [xy(i,:),app.dataplot(i)] = GetDataTips(app,'_',10,'g',2);

            end

            app.lineplot = plot(app.UIAxesPlayer,xy(:,1),xy(:,2),'g','MarkerSize',10,'LineWidth',2);

            % Distance in pxl between the two coordinate
            app.DistPxl = abs(xy(1,2)-xy(2,2));
            % Open the settings dialog and pass inputs
            app.DialogAppScaleData = DialogAppScale(app,app.DistPxl,app.DistMm,app.Scale);

            app.ActionDescription.Visible ='off';
            app.Button.Visible = 'off';
        end

        % Button pushed function: ApoInf
        function ApoInfButtPush(app, event)

            % Open the Apo dialog and pass inpusize(app.frame{1},2)ts
            app.SupInfApo = "Inferior";
            app.DialogAppApo = DialogAppAponeurosis(app,app.SupInfApo);
        end

        % Button pushed function: Fascicle
        function FlButtPush(app, event)
            app.DialogAppFl = DialogAppFascicles(app,app.NbFl,app.FlParts);
        end

        % Button pushed function: Run
        function RunButtPush(app, event)

            if app.FlCorr == 1  % Fl Correction Settings
                NbFlCorr = app.FlnCorr;
                StartFl = app.FlnCorr;
            elseif app.FlCorr == 0 && app.ApoSupCorr==1 || app.ApoInfCorr==1
                NbFlCorr =  app.NbFl;
                StartFl = 1;
                app.FlCorr = 0;
            else % Default Settings
                NbFlCorr =  app.NbFl;
                StartFl = 1;
                app.FlCorr = 1;
                app.ApoSupCorr = 1;
                app.ApoInfCorr = 1;
                PreAllocateRun(app,StartFl,NbFlCorr)
            end

            d = uiprogressdlg(app.UStrackFig,'Title','Loading...',...
                'Message','Please, wait for the tracking computation.','Indeterminate','on','Cancelable','on');
            drawnow

            %% Tracker Settings and computation
            % Aponeurosis Superior
            % Settings
            if app.ApoSupCorr == 1
                app.Apo.SupTrack = vision.PointTracker('NumPyramidLevels',app.SaveSettgs(4),'MaxBidirectionalError',...
                    app.SaveSettgs(5),'BlockSize',[app.SaveSettgs(6) app.SaveSettgs(6)],'MaxIterations',app.SaveSettgs(7));
                initialize(app.Apo.SupTrack,app.ApoSupPos,app.frame{app.Spinner.Value})

                for fr = app.Spinner.Value:app.nbFrame %Computation
                    [ApoSupPoints,ApoSupValidity] = app.Apo.SupTrack(app.frame{fr}); %Track Point
                    ApoSupPoints = ApoSupPoints(ApoSupValidity,:); % Delete point out of the range true tracking
                    app.Apo.EqLinSup(fr,:) = polyfit(ApoSupPoints(:,1), ApoSupPoints(:,2), 1); % Linear regression
                end
            end
            % Cancel Dialog progress bar
            if d.CancelRequested
                return
            end

            % Aponeurosis Inferior
            %Settings
            if app.ApoInfCorr == 1
                app.Apo.InfTrack = vision.PointTracker('NumPyramidLevels',app.SaveSettgs(4),'MaxBidirectionalError',...
                    app.SaveSettgs(5),'BlockSize',[app.SaveSettgs(6) app.SaveSettgs(6)],'MaxIterations',app.SaveSettgs(7));
                initialize(app.Apo.InfTrack,app.ApoInfPos,app.frame{app.Spinner.Value})

                for fr = app.Spinner.Value:app.nbFrame %Computation
                    [ApoInfPoints,ApoInfValidity] = app.Apo.InfTrack(app.frame{fr});
                    ApoInfPoints = ApoInfPoints(ApoInfValidity,:);
                    app.Apo.EqLinInf(fr,:) = polyfit(ApoInfPoints(:,1), ApoInfPoints(:,2), 1);
                end
            end
            % Cancel Dialog progress bar
            if d.CancelRequested
                return
            end

            % Fascicle
            if app.FlCorr == 1
                for fl = StartFl:NbFlCorr %Settings
                    app.Fl(fl).Track = vision.PointTracker('NumPyramidLevels',app.SaveSettgs(4),'MaxBidirectionalError',...
                        app.SaveSettgs(5),'BlockSize',[app.SaveSettgs(6) app.SaveSettgs(6)],'MaxIterations',app.SaveSettgs(7));
                    initialize(app.Fl(fl).Track,app.FlPos{fl},app.frame{app.Spinner.Value})
                end

                for fr = app.Spinner.Value:app.nbFrame %Computation
                    for  fl = StartFl:app.NbFl
                        [FlPoints,FlValidity] = app.Fl(fl).Track(app.frame{fr});
                        FlPoints = FlPoints(FlValidity,:);
                        app.Fl(fl).EqLin(fr,:) = polyfit(FlPoints(:,1), FlPoints(:,2), 1);
                    end
                end
            end

            % Cancel Dialog progress bar
            if d.CancelRequested
                return
            end

            %% Computation of the variables of interest
            % Angle Calculation
            for  fl = StartFl:NbFlCorr
                for fr = app.Spinner.Value:app.nbFrame
                    app.Fl(fl).SupAngle(fr,1) = AngleTrack(app,app.Fl(fl).EqLin(fr,:),app.Apo.EqLinSup(fr,:));
                    app.Fl(fl).InfAngle(fr,1) = AngleTrack(app,app.Fl(fl).EqLin(fr,:),app.Apo.EqLinInf(fr,:));
                end
            end

            % Fascicle Lenght
            for  fl = StartFl:NbFlCorr
                for fr = app.Spinner.Value:app.nbFrame
                    app.Fl(fl).IntersectSup(fr,:) = CoordIntersec(app,app.Fl(fl).EqLin(fr,:),app.Apo.EqLinSup(fr,:));
                    app.Fl(fl).IntersectInf(fr,:) = CoordIntersec(app,app.Fl(fl).EqLin(fr,:),app.Apo.EqLinInf(fr,:));
                    app.Fl(fl).Lenght(fr,1) = norm(app.Fl(fl).IntersectSup(fr,:)-app.Fl(fl).IntersectInf(fr,:))/app.Scale;
                end
            end
            % Fascicle Velocity
            for  fl = StartFl:NbFlCorr
                app.Fl(fl).Velocity = gradient(app.Fl(fl).Lenght,1/round(app.frameRate));
            end

            %  Use the linear regression equation to reconstruct the aponevrosis and the fascicle.
            for fr = app.Spinner.Value:app.nbFrame
                app.Apo.lineSup{fr} = app.Apo.EqLinSup(fr,1).*(1:app.Width)+app.Apo.EqLinSup(fr,2);
                app.Apo.lineInf{fr} = app.Apo.EqLinInf(fr,1).*(1:app.Width)+app.Apo.EqLinInf(fr,2);
                for fl = StartFl:app.NbFl
                    app.Fl(fl).line{fr} =  app.Fl(fl).EqLin(fr,1).*(1:app.Width)+app.Fl(fl).EqLin(fr,2);
                end
            end

            % Muscle Thickness
            for fr = app.Spinner.Value:app.nbFrame
                app.Apo.MuscleThickness(fr,1)=mean(abs(app.Apo.lineInf{fr}(:)-app.Apo.lineSup{fr}(:))/app.Scale);
            end

            % Cancel Dialog progress bar
            if d.CancelRequested
                return
            end

            % Plot Figure
            PlotResults(app)

            % Tab
            TabCreation(app)
            app.liveIM = plotLiveFrame(app, app.frameBW, app.Spinner.Value) ;

            % Correction Button
            app.ButtonCorrection.Visible ='on';
            app.ApoSupCorr = 0;
            app.ApoInfCorr = 0;
            app.FlCorr = 0;
            app.dcm_obj.Enable='off';
            close(d)
        end

        % Value changed function: ScalepxlmmEditField
        function ScaleChangedFcn(app, event)
            value = app.ScalepxlmmEditField.Value;
            app.Scale=value;
        end

        % Button pushed function: ButtonCorrection
        function CorrectionsButtPush(app, event)
            axes(app.UIAxesPlayer) %Handle the axis that displays the frames
            app.DialogAppCorr = DialogAppCorrections(app,app.NbFl);
        end

        % Clicked callback: OpenPushMenu
        function OpenPush(app, event)
            app.FlCorr = 0 ; % Allows the resetting of the variables of interest (Fl and Apo)
            app.ApoSupCorr = 0;
            app.ApoInfCorr = 0;
            app.Corr = 0;
            app.nbPartsApo = [0;0];
            app.dcm_obj.Enable='on';
            % Enable PushButton
            app.UITable.Data = {};
            app.ButtonCorrection.Visible = 'off';
            app.ButtonCorrection.Visible = 'off';
            app.TabGroup.Visible = 'off';
            app.Fascicle.Enable = 'off';
            app.ApoInf.Enable = 'off';
            app.Run.Enable = 'off';
            app.AutoCrop.Enable = 'on';
            app.ManuCrop.Enable = 'on';
            app.ApoSup.Enable = 'on';
            app.ScaleBtn.Enable = 'on';
            app.Slider.Enable = 'on' ;
            app.Spinner.Enable = 'on' ;
            app.ScalepxlmmEditFieldLabel.Enable = 'on' ;
            app.ScalepxlmmEditField.Enable = 'on' ;
            app.TimeEditField.Enable = 'on';

            % Open Video File
            [file,path] = uigetfile({'*.mp4;*.m4v;*.avi;*.mpeg;*.wmv;*.mov',...
                'Video Files (*.mp4,*.m4v,*.avi,*.mpeg,*.wmv,*.mov'}, ...
                'Select a video File');

            if file==0
                return
            end

            % Put the focus back on the main app
            drawnow;
            figure(app.UStrackFig);

            % Ui progresse dlg bar
            d = uiprogressdlg(app.UStrackFig,'Title','Loading...',...
                'Message','Please, wait for the video to open.','Indeterminate','on','Cancelable','on');
            drawnow

            echoVideo = VideoReader(fullfile(path,file));
            app.nbFrame = echoVideo.NumFrames;
            app.Height = echoVideo.Height;
            app.Width = echoVideo.Width;
            app.frameRate = echoVideo.FrameRate;
            app.time = 0:1/round(app.frameRate):(app.nbFrame-1)/round(app.frameRate);
            app.time = app.time';

            % Adapts Limit of Spinner and Slider
            app.Spinner.Limits = [1,app.nbFrame];
            app.Slider.Limits = [1,app.nbFrame];

            % Load all frames
            app.frame = cell(1,app.nbFrame); %Preallocate
            app.frame(:) = {zeros(app.Height,app.Width,3,'uint8')};
            for n = 1:app.nbFrame
                app.frame{n} = read(echoVideo,n);
                % Cancel Dialog progress bar
                if d.CancelRequested
                    break
                end
            end

            app.BWorColor = "Color";
            app.liveIM = plotLiveFrame(app,app.frame, app.Spinner.Value) ;
            close(d)
        end

        % Clicked callback: SavePushMenu
        function SettPush(app, event)
            % Open the settings dialog and pass inputs
            app.DialogAppSet = DialogAppSettings(app, app.SaveSettgs);
        end

        % Clicked callback: SettingsPushMenu
        function SavePush(app, event)
            app.DialogAppSaveDAT = DialogAppSave(app, app.NbFl);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UStrackFig = uifigure('Visible', 'off');
            app.UStrackFig.Name = 'MATLAB App';
            app.UStrackFig.CloseRequestFcn = createCallbackFcn(app, @UStrackFigCloseRequest, true);
            app.UStrackFig.KeyPressFcn = createCallbackFcn(app, @KeyPressFcn, true);
            app.Menu = uitoolbar(app.UStrackFig);
            app.OpenPushMenu = uipushtool(app.Menu);
            app.OpenPushMenu.ClickedCallback = createCallbackFcn(app, @OpenPush, true);
            app.OpenPushMenu.Icon = 'add_24.png';
            app.SettingsPushMenu = uipushtool(app.Menu);
            app.SettingsPushMenu.ClickedCallback = createCallbackFcn(app, @SavePush, true);
            app.SettingsPushMenu.Icon = 'Save_24.png';
            app.SavePushMenu = uipushtool(app.Menu);
            app.SavePushMenu.ClickedCallback = createCallbackFcn(app, @SettPush, true);
            app.SavePushMenu.Icon = 'Icon_Settings_24.png';
            app.GLUStrack = uigridlayout(app.UStrackFig);
            app.GLsettings = uigridlayout(app.GLUStrack);
            app.Step1ImageProcessingPanel = uipanel(app.GLsettings);
            app.Step1ImageProcessingPanel.Title = 'Step 1 : Image Processing ';
            app.GLbutton = uigridlayout(app.Step1ImageProcessingPanel);
            app.AutoCrop = uibutton(app.GLbutton, 'push');
            app.AutoCrop.ButtonPushedFcn = createCallbackFcn(app, @AutoCropVid, true);
            app.AutoCrop.Icon = 'AutoCrop.png';
            app.AutoCrop.Enable = 'off';
            app.AutoCrop.Text = '';
            app.ScalepxlmmEditField = uieditfield(app.GLbutton, 'numeric');
            app.ScalepxlmmEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleChangedFcn, true);
            app.ScalepxlmmEditField.Enable = 'off';
            app.ScalepxlmmEditField.Value = 1;
            app.ScalepxlmmEditFieldLabel = uilabel(app.GLbutton);
            app.ScalepxlmmEditFieldLabel.Enable = 'off';
            app.ScalepxlmmEditFieldLabel.Text = 'Scale (pxl/mm)';
            app.ScaleBtn = uibutton(app.GLbutton, 'push');
            app.ScaleBtn.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtPush, true);
            app.ScaleBtn.Icon = 'Scale.png';
            app.ScaleBtn.Enable = 'off';
            app.ScaleBtn.Text = '';
            app.ManuCrop = uibutton(app.GLbutton, 'push');
            app.ManuCrop.ButtonPushedFcn = createCallbackFcn(app, @ManuCropVid, true);
            app.ManuCrop.Icon = 'ManualCrop.png';
            app.ManuCrop.Enable = 'off';
            app.ManuCrop.Text = '';
            app.Step2AnalysisPanel = uipanel(app.GLsettings);
            app.Step2AnalysisPanel.Title = 'Step 2 : Analysis';
            app.GLbutton2 = uigridlayout(app.Step2AnalysisPanel);
            app.ApoSup = uibutton(app.GLbutton2, 'push');
            app.ApoSup.ButtonPushedFcn = createCallbackFcn(app, @ApoSupButtPush, true);
            app.ApoSup.Icon = 'SupApo2.png';
            app.ApoSup.Enable = 'off';
            app.ApoSup.Text = '';
            app.Fascicle = uibutton(app.GLbutton2, 'push');
            app.Fascicle.ButtonPushedFcn = createCallbackFcn(app, @FlButtPush, true);
            app.Fascicle.Icon = 'fascicle2.png';
            app.Fascicle.Enable = 'off';
            app.Fascicle.Text = {''; ''};
            app.ApoInf = uibutton(app.GLbutton2, 'push');
            app.ApoInf.ButtonPushedFcn = createCallbackFcn(app, @ApoInfButtPush, true);
            app.ApoInf.Icon = 'InfApo2.png';
            app.ApoInf.Enable = 'off';
            app.ApoInf.Text = '';
            app.Run = uibutton(app.GLbutton2, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunButtPush, true);
            app.Run.Icon = 'Run2.png';
            app.Run.Enable = 'off';
            app.Run.Text = '';
            app.TabGroup = uitabgroup(app.GLsettings);
            app.TabGroup.Visible = 'off';
            app.FlTab = uitab(app.TabGroup);
            app.FlTab.Title = 'Fl';
            app.GLfigFl = uigridlayout(app.FlTab);
            app.UIAxesFigFl = uiaxes(app.GLfigFl);
            title(app.UIAxesFigFl, 'Title')
            xlabel(app.UIAxesFigFl, 'X')
            ylabel(app.UIAxesFigFl, 'Y')
            zlabel(app.UIAxesFigFl, 'Z')
            app.FvTab = uitab(app.TabGroup);
            app.FvTab.Title = 'Fv';
            app.GLfigFv = uigridlayout(app.FvTab);
            app.UIAxesFigFv = uiaxes(app.GLfigFv);
            title(app.UIAxesFigFv, 'Title')
            xlabel(app.UIAxesFigFv, 'X')
            ylabel(app.UIAxesFigFv, 'Y')
            zlabel(app.UIAxesFigFv, 'Z')
            app.MtTab = uitab(app.TabGroup);
            app.MtTab.Title = 'Mt';
            app.GLfigMt = uigridlayout(app.MtTab);
            app.UIAxesFigMt = uiaxes(app.GLfigMt);
            title(app.UIAxesFigMt, 'Title')
            xlabel(app.UIAxesFigMt, 'X')
            ylabel(app.UIAxesFigMt, 'Y')
            zlabel(app.UIAxesFigMt, 'Z')
            app.PenTab = uitab(app.TabGroup);
            app.PenTab.Title = 'Pen';
            app.GLfigPen = uigridlayout(app.PenTab);
            app.UIAxesFigPen = uiaxes(app.GLfigPen);
            title(app.UIAxesFigPen, 'Title')
            xlabel(app.UIAxesFigPen, 'X')
            ylabel(app.UIAxesFigPen, 'Y')
            zlabel(app.UIAxesFigPen, 'Z')
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.GLtable = uigridlayout(app.DataTab);
            app.UITable = uitable(app.GLtable);
            app.UITable.ColumnName = {'t (s)'; 'Fl (mm)'; 'Fv (mm/s)'; '𝛼 (°)'; 'Mt (mm)'};
            app.UITable.RowName = {};
            app.GLplayer = uigridlayout(app.GLUStrack);
            app.UIAxesPlayer = uiaxes(app.GLplayer);
            title(app.UIAxesPlayer, {''; ''})
            app.UIAxesPlayer.XTick = [];
            app.UIAxesPlayer.YTick = [];
            app.GLplayerParam = uigridlayout(app.GLplayer);
            app.Label = uilabel(app.GLplayerParam);
            app.Label.Text = '';
            app.Spinner = uispinner(app.GLplayerParam);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValChanged, true);
            app.Spinner.Enable = 'off';
            app.Spinner.Value = 1;
            app.Slider = uislider(app.GLplayerParam);
            app.Slider.Limits = [1 100];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.Slider.Value = 1;
            app.GridLayout = uigridlayout(app.GLplayer);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ContinueFcn, true);
            app.Button.Icon = 'Next.png';
            app.Button.Visible = 'off';
            app.Button.Text = '';
            app.TimeEditField = uieditfield(app.GridLayout, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%.2f s';
            app.TimeEditField.Editable = 'off';
            app.TimeEditField.Enable = 'off';
            app.ButtonCorrection = uibutton(app.GridLayout, 'push');
            app.ButtonCorrection.ButtonPushedFcn = createCallbackFcn(app, @CorrectionsButtPush, true);
            app.ButtonCorrection.Icon = 'Correction.png';
            app.ButtonCorrection.Visible = 'off';
            app.ButtonCorrection.Text = '';
            app.ActionDescription = uilabel(app.GLplayer);
            app.ActionDescription.Interpreter = 'html';
            app.ActionDescription.Visible = 'off';
            app.ActionDescription.Text = ' ';
            % Show the figure after all components are created
            app.UStrackFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MuscleTrack_1_3_1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UStrackFig)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UStrackFig)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UStrackFig)
        end
    end
end